classdef input1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        AmplitudeLabel              matlab.ui.control.Label
        AmplitudeEditField          matlab.ui.control.NumericEditField
        PeriodornumberofstepsLabel  matlab.ui.control.Label
        FrequencyEditField          matlab.ui.control.NumericEditField
        OKButton                    matlab.ui.control.Button
        OffsetormagnitudeofconstantoutputLabel  matlab.ui.control.Label
        OffsetormagnitudeofconstantoutputEditField  matlab.ui.control.NumericEditField
        SettingPressure1InputLabel  matlab.ui.control.Label
        StartTimeLabel              matlab.ui.control.Label
        StartTimeEditField          matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        CallingApp % mainapp
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, a, f, d, st)
            app.CallingApp=mainapp;
            
            %Update input values
            app.FrequencyEditField.Value=f;
            app.AmplitudeEditField.Value=a;
            app.OffsetormagnitudeofconstantoutputEditField.Value=d;
            app.StartTimeEditField.Value=st;
            

        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            %call mainapp's public function for plotting
            updateplot1(app.CallingApp,app.AmplitudeEditField.Value,app.FrequencyEditField.Value,app.OffsetormagnitudeofconstantoutputEditField.Value,app.StartTimeEditField.Value);
            delete(app)           
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.CallingApp.Setting1Button.Enable='on';
            delete(app)          
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.AmplitudeLabel = uilabel(app.UIFigure);
            app.AmplitudeLabel.Text = 'Amplitude';
            app.AmplitudeEditField = uieditfield(app.UIFigure, 'numeric');
            app.PeriodornumberofstepsLabel = uilabel(app.UIFigure);
            app.PeriodornumberofstepsLabel.Text = 'Period(or number of steps)';
            app.FrequencyEditField = uieditfield(app.UIFigure, 'numeric');
            app.OKButton = uibutton(app.UIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.OffsetormagnitudeofconstantoutputLabel = uilabel(app.UIFigure);
            app.OffsetormagnitudeofconstantoutputLabel.Text = 'Offset (or magnitude of constant output)';
            app.OffsetormagnitudeofconstantoutputEditField = uieditfield(app.UIFigure, 'numeric');
            app.SettingPressure1InputLabel = uilabel(app.UIFigure);
            app.SettingPressure1InputLabel.Text = 'Setting Pressure 1 Input';
            app.StartTimeLabel = uilabel(app.UIFigure);
            app.StartTimeLabel.Text = 'Start Time';
            app.StartTimeEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = input1(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end