classdef mainapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        SettingTab                   matlab.ui.container.Tab
        UIAxesinput                  matlab.ui.control.UIAxes
        SendButton                   matlab.ui.control.Button
        OKButton                     matlab.ui.control.Button
        Image                        matlab.ui.control.Image
        TextArea                     matlab.ui.control.TextArea
        ResetButton_2                matlab.ui.control.Button
        Panel                        matlab.ui.container.Panel
        Setting1Button               matlab.ui.control.Button
        Setting2Button               matlab.ui.control.Button
        Setting3Button               matlab.ui.control.Button
        userdefineButton_2           matlab.ui.control.StateButton
        pressure1ListBoxLabel        matlab.ui.control.Label
        pressure1ListBox             matlab.ui.control.ListBox
        pressure2ListBoxLabel        matlab.ui.control.Label
        pressure2ListBox             matlab.ui.control.ListBox
        pressure3ListBoxLabel        matlab.ui.control.Label
        pressure3ListBox             matlab.ui.control.ListBox
        Panel_2                      matlab.ui.container.Panel
        AuroraTrackingSystemLabel_2  matlab.ui.control.Label
        InitializationButton         matlab.ui.control.Button
        ModeDropDownLabel            matlab.ui.control.Label
        ModeDropDown                 matlab.ui.control.DropDown
        PortLabel                    matlab.ui.control.Label
        COM                          matlab.ui.control.DropDown
        BaudRateLabel                matlab.ui.control.Label
        baudrate                     matlab.ui.control.NumericEditField
        TimesLabel                   matlab.ui.control.Label
        time                         matlab.ui.control.NumericEditField
        ControlPeriodsLabel          matlab.ui.control.Label
        ControlPeriod                matlab.ui.control.NumericEditField
        PositionPressureTab          matlab.ui.container.Tab
        SaveDataButton               matlab.ui.control.Button
        Image_2                      matlab.ui.control.Image
        PressurePanel                matlab.ui.container.Panel
        UIAxesP1                     matlab.ui.control.UIAxes
        UIAxesP2                     matlab.ui.control.UIAxes
        UIAxesP3                     matlab.ui.control.UIAxes
        PositionPanel                matlab.ui.container.Panel
        UIAxes                       matlab.ui.control.UIAxes
        UIAxesposition               matlab.ui.control.UIAxes
        UIAxestheta                  matlab.ui.control.UIAxes
        UIAxesbending                matlab.ui.control.UIAxes
        RobotlengthmmLabel           matlab.ui.control.Label
        Robotlength                  matlab.ui.control.NumericEditField
        ForceTab                     matlab.ui.container.Tab
        SaveDataButton_2             matlab.ui.control.Button
        Image2                       matlab.ui.control.Image
        ForcePanel                   matlab.ui.container.Panel
        UIAxesFP1                    matlab.ui.control.UIAxes
        UIAxesFP2                    matlab.ui.control.UIAxes
        UIAxesFP3                    matlab.ui.control.UIAxes
        Panel_4                      matlab.ui.container.Panel
        UIAxesFT                     matlab.ui.control.UIAxes
        TorquePanel                  matlab.ui.container.Panel
        UIAxesTP1                    matlab.ui.control.UIAxes
        UIAxesTP2                    matlab.ui.control.UIAxes
        UIAxesTP3                    matlab.ui.control.UIAxes
        Panel_5                      matlab.ui.container.Panel
        UIAxesTT                     matlab.ui.control.UIAxes
        StiffnessTab                 matlab.ui.container.Tab
        SaveDataButton_3             matlab.ui.control.Button
        PositionPanel_2              matlab.ui.container.Panel
        UIAxesS1                     matlab.ui.control.UIAxes
        UIAxesS2                     matlab.ui.control.UIAxes
        StiffnessPanel               matlab.ui.container.Panel
        UIAxesS3                     matlab.ui.control.UIAxes
        UIAxesS4                     matlab.ui.control.UIAxes
        StiffnessPanel_2             matlab.ui.container.Panel
        xNmmEditFieldLabel           matlab.ui.control.Label
        xNmmEditField                matlab.ui.control.NumericEditField
        yNmmEditFieldLabel           matlab.ui.control.Label
        yNmmEditField                matlab.ui.control.NumericEditField
        zNmmEditFieldLabel           matlab.ui.control.Label
        zNmmEditField                matlab.ui.control.NumericEditField
        Panel_3                      matlab.ui.container.Panel
        xgEditFieldLabel             matlab.ui.control.Label
        xgEditField                  matlab.ui.control.NumericEditField
        ygEditFieldLabel             matlab.ui.control.Label
        ygEditField                  matlab.ui.control.NumericEditField
        zgEditFieldLabel             matlab.ui.control.Label
        zgEditField                  matlab.ui.control.NumericEditField
        OKButton_2                   matlab.ui.control.Button
        LoadLabel                    matlab.ui.control.Label
        DefelctionPanel              matlab.ui.container.Panel
        delta_xmmEditFieldLabel      matlab.ui.control.Label
        delta_xmmEditField           matlab.ui.control.NumericEditField
        delta_ymmEditFieldLabel      matlab.ui.control.Label
        delta_ymmEditField           matlab.ui.control.NumericEditField
        delta_zmmEditFieldLabel      matlab.ui.control.Label
        delta_zmmEditField           matlab.ui.control.NumericEditField
        Image3                       matlab.ui.control.Image
        InstructionTab               matlab.ui.container.Tab
        TextArea_2                   matlab.ui.control.TextArea
        Image4                       matlab.ui.control.Image
    end

    
    properties (Access = private)
        DialogApp_1 % Description
        FrequencyValue_1=0
        AmplitudeValue_1=0
        DisplacementValue_1=0
        StartTimeValue_1=0
        DialogApp_2
        FrequencyValue_2=0
        AmplitudeValue_2=0
        DisplacementValue_2=0
        StartTimeValue_2=0
        DialogApp_3
        FrequencyValue_3=0
        AmplitudeValue_3=0
        DisplacementValue_3=0
        StartTimeValue_3=0
        
        
    end
    
    
    methods (Access=private)
        function setPromptFcn(app,jTextArea,eventData,newPrompt)
            % Prevent overlapping reentry due to prompt replacement
            persistent inProgress
            if isempty(inProgress)
                inProgress = 1;  %#ok unused
            else
                return;
            end
            
            try
                % *** Prompt modification code goes here ***
                cwText = char(jTextArea.getText);
                app.TextArea.Value = cwText;
                % force prompt-change callback to fizzle-out...
                pause(0.02);
            catch
                % Never mind - ignore errors...
            end
            
            % Enable new callbacks now that the prompt has been modified
            inProgress = [];
            
        end  % setPromptFcn % Description
    end


methods (Access=public)

function updateplot1(app,a_1,f_1,d_1,st_1)

%store input as properties
app.FrequencyValue_1=f_1;
app.AmplitudeValue_1=a_1;
app.DisplacementValue_1=d_1;
app.StartTimeValue_1=st_1;
setting1(app,a_1,f_1,d_1,st_1);
%plot(app.UIAxesinput,n1,y1)


%Re-enable setting button1
app.Setting1Button.Enable='on';
end

function updateplot2(app,a_2,f_2,d_2,st_2)

%Store input as properties
app.FrequencyValue_2=f_2;
app.AmplitudeValue_2=a_2;
app.DisplacementValue_2=d_2;
app.StartTimeValue_2=st_2;
setting2(app,a_2,f_2,d_2,st_2);
%plot(app.UIAxesinput,n2,y2)

%Re-enable setting button2
app.Setting2Button.Enable='on';
end

function updateplot3(app,a_3,f_3,d_3,st_3)

%Store input as properties
app.FrequencyValue_3=f_3;
app.AmplitudeValue_3=a_3;
app.DisplacementValue_3=d_3;
app.StartTimeValue_3=st_3;
setting3(app,a_3,f_3,d_3,st_3);
%plot(app.UIAxesinput,n3,y3)

%Re-enable setting button3
app.Setting3Button.Enable='on';
end

end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc
            app.baudrate.Value=9600;
            app.time.Value=10;
            app.ControlPeriod.Value=0.1;
            app.SaveDataButton_2.Enable='off';
            app.SaveDataButton_3.Enable='off';
            app.UIFigure.AutoResizeChildren='on';
            %app.Image_2.Parent=app.UIFigure;
            %app.UIFigure.Scrollable='on';
            global time ControlPeriod baudrate force_applied_x force_applied_y force_applied_z
            time=app.time.Value;
            ControlPeriod=app.ControlPeriod.Value;
            baudrate=app.baudrate.Value;
            force_applied_x=0;
            force_applied_y=0;
            force_applied_z=0;


            try
                jDesktop = com.mathworks.mde.desk.MLDesktop.getInstance;
                jCmdWin = jDesktop.getClient('Command Window');
                jTextArea = jCmdWin.getComponent(0).getViewport.getView;
                set(jTextArea,'CaretUpdateCallback',@app.setPromptFcn)
            catch
                warndlg('fatal error');
            end
            
            
        end

        % Button pushed function: Setting1Button
        function Setting1ButtonPushed(app, event)
            
            %Disable plot option while input1 is open
            app.Setting1Button.Enable='off';
            
            %Open the input1 and pass inputs
            app.DialogApp_1=input1(app,app.AmplitudeValue_1,app.FrequencyValue_1,app.DisplacementValue_1,app.StartTimeValue_1);
            
        end

        % Button pushed function: InitializationButton
        function InitializationButtonPushed(app, event)
 
        if app.ModeDropDown.Value=="position tracking"
        disp('initializing...')
        initialization;
        elseif app.ModeDropDown.Value=="force identification"
        disp('initializing...')
        initialise_force_sensor;
        elseif app.ModeDropDown.Value=="stiffness identification"
        disp('initializing...')
        initialization;
        end


        end

        % Button pushed function: SendButton
        function SendButtonPushed(app, event)
            app.SendButton.Enable='off';
            disp('sending the input pressure...')
            %send the pwm value
            global pwmtemp1 pwmtemp2 pwmtemp3 y1 y2 y3 DAQ_pressure DAQ_cmd_pressure Aurora_position Aurora_quat Aurora_theta force_raw force torque_raw torque step l
            
            pwmtemp1=y1./3;
            pwmtemp2=y2./3;
            pwmtemp3=y3./3;
            
            if app.ModeDropDown.Value=="position tracking"
            pwmtemp1=y1./3;
            pwmtemp2=y2./3;
            pwmtemp3=y3./3;
                
                [~,theta]=send_input_position;
                
                
                %diagram for theoretical and actual input pressure
                plot(app.UIAxesP1,DAQ_pressure.Time, DAQ_pressure.Var1(:,1),...
                    DAQ_cmd_pressure.Time, DAQ_cmd_pressure.Var1(:,1));
                legend(app.UIAxesP1,"desired P1","theoretical P1")
                ylim(app.UIAxesP1,[0,3]);
                plot(app.UIAxesP2,DAQ_pressure.Time, DAQ_pressure.Var1(:,2),...
                    DAQ_cmd_pressure.Time, DAQ_cmd_pressure.Var1(:,2));
                legend(app.UIAxesP2,"desired P2","theoretical P2")
                ylim(app.UIAxesP2,[0,3]);
                plot(app.UIAxesP3,DAQ_pressure.Time, DAQ_pressure.Var1(:,3),...
                    DAQ_cmd_pressure.Time, DAQ_cmd_pressure.Var1(:,3));
                legend(app.UIAxesP3,"desired P3","theoretical P3")
                ylim(app.UIAxesP3,[0,3]);
                
                %diagram for the position of the manipulator
                plot(app.UIAxesposition,Aurora_position.Time,Aurora_position.Var1(:,1).*1000,...
                    Aurora_position.Time,Aurora_position.Var1(:,2).*1000,...
                    Aurora_position.Time,Aurora_position.Var1(:,3).*1000);
                legend(app.UIAxesposition,"x","y","z")
                
                plot3(app.UIAxes,Aurora_position.Var1(:,1).*1000,...
                    Aurora_position.Var1(:,2).*1000,...
                    Aurora_position.Var1(:,3).*1000,'*');
                
                plot(app.UIAxestheta,Aurora_theta.Time,theta);
                
                %diagram for the bending of the manipulator
                x = Aurora_position.Var1(:,1) - Aurora_position.Var1(1,1);
                y = Aurora_position.Var1(:,2) - Aurora_position.Var1(1,2);
                %0.0324 should be changed into the distance from aurora to
                %robot tip
                l=app.Robotlength.Value;
                z =-0.0324-Aurora_position.Var1(:,3)+l;
                plot3(app.UIAxesbending,x.*1000,y.*1000,z.*1000,'*');
                
            elseif app.ModeDropDown.Value=="force identification"
                            pwmtemp1=y1./3;
            pwmtemp2=y2./3;
            pwmtemp3=y3./3;
            y1max=max(y1);
            y2max=max(y2);
            y3max=max(y3);
            n=[y1max,y2max,y3max];
            ymax=max(n);
                send_input_force;
                
                %force_pressure diagram
                plot(app.UIAxesFP1,DAQ_pressure.Var1(1:step:end,1),force_raw(:,1),...
                    DAQ_pressure.Var1(1:step:end,1),force_raw(:,2),...
                    DAQ_pressure.Var1(1:step:end,1),force_raw(:,3));
                legend(app.UIAxesFP1,"x-direction","y-direction","z-direction")
                xlim(app.UIAxesFP1,[0,ymax])
                plot(app.UIAxesFP2,DAQ_pressure.Var1(1:step:end,2),force_raw(:,1),...
                    DAQ_pressure.Var1(1:step:end,2),force_raw(:,2),...
                    DAQ_pressure.Var1(1:step:end,2),force_raw(:,3));
                legend(app.UIAxesFP2,"x-direction","y-direction","z-direction");
                xlim(app.UIAxesFP2,[0,ymax])
                plot(app.UIAxesFP3,DAQ_pressure.Var1(1:step:end,3),force_raw(:,1),...
                    DAQ_pressure.Var1(1:step:end,3),force_raw(:,2),...
                    DAQ_pressure.Var1(1:step:end,3),force_raw(:,3));
                legend(app.UIAxesFP3,"x-direction","y-direction","z-direction");
                xlim(app.UIAxesFP3,[0,ymax])
                plot(app.UIAxesFT,force.Time,force_raw(:,1),...
                    force.Time,force_raw(:,2),...
                    force.Time,force_raw(:,3))
                legend(app.UIAxesFT,"x-direction","y-direction","z-direction");
                %torque_pressure diagram
                plot(app.UIAxesTP1,DAQ_pressure.Var1(1:step:end,1),torque_raw(:,1),...
                    DAQ_pressure.Var1(1:step:end,1),torque_raw(:,2),...
                    DAQ_pressure.Var1(1:step:end,1),torque_raw(:,3));
                legend(app.UIAxesTP1,"x-direction","y-direction","z-direction");
                xlim(app.UIAxesTP1,[0,ymax])
                plot(app.UIAxesTP2,DAQ_pressure.Var1(1:step:end,2),torque_raw(:,1),...
                    DAQ_pressure.Var1(1:step:end,2),torque_raw(:,2),...
                    DAQ_pressure.Var1(1:step:end,2),torque_raw(:,3));
                legend(app.UIAxesTP2,"x-direction","y-direction","z-direction");
                xlim(app.UIAxesTP2,[0,ymax])
                plot(app.UIAxesTP3,DAQ_pressure.Var1(1:step:end,3),torque_raw(:,1),...
                    DAQ_pressure.Var1(1:step:end,3),torque_raw(:,2),...
                    DAQ_pressure.Var1(1:step:end,3),torque_raw(:,3));
                legend(app.UIAxesTP3,"x-direction","y-direction","z-direction");
                plot(app.UIAxesTT,torque.Time,torque_raw(:,1),...
                    torque.Time,torque_raw(:,2),...
                    torque.Time,torque_raw(:,3))
                legend(app.UIAxesTT,"x-direction","y-direction","z-direction");
                xlim(app.UIAxesTP3,[0,ymax])
                
                
            elseif app.ModeDropDown.Value=="chamber actuation"
                            pwmtemp1=y1./3;
            pwmtemp2=y2./3;
            pwmtemp3=y3./3;
                send_input_pressure;
                %diagram for theoretical and actual input pressure
                plot(app.UIAxesP1,DAQ_pressure.Time, DAQ_pressure.Var1(:,1),...
                    DAQ_cmd_pressure.Time, DAQ_cmd_pressure.Var1(:,1));
                legend(app.UIAxesP1,"desired P1","theoretical P1")
                ylim(app.UIAxesP1,[0,3]);
                plot(app.UIAxesP2,DAQ_pressure.Time, DAQ_pressure.Var1(:,2),...
                    DAQ_cmd_pressure.Time, DAQ_cmd_pressure.Var1(:,2));
                legend(app.UIAxesP2,"desired P2","theoretical P2")
                ylim(app.UIAxesP2,[0,3]);
                plot(app.UIAxesP3,DAQ_pressure.Time, DAQ_pressure.Var1(:,3),...
                    DAQ_cmd_pressure.Time, DAQ_cmd_pressure.Var1(:,3));
                legend(app.UIAxesP3,"desired P3","theoretical P3")
                ylim(app.UIAxesP3,[0,3]);
                
            elseif app.ModeDropDown.Value=="stiffness identification"
            pwmtemp1=max(y1)./3;
            pwmtemp2=max(y2)./3;
            pwmtemp3=max(y3)./3;
                send_input_stiffness;
                global  x_1 y_1 z_1
                %t_1=Aurora_position.Time;
                x_1=Aurora_position.Var1(:,1);
                y_1=Aurora_position.Var1(:,2);
                z_1=Aurora_position.Var1(:,3);
                plot(app.UIAxesS1,Aurora_position.Time,x_1.*1000,...
                    Aurora_position.Time,y_1.*1000,...
                    Aurora_position.Time,z_1.*1000);
                legend(app.UIAxesS1,"x","y","z")
                
                plot3(app.UIAxesS2,x_1.*1000,...
                    y_1.*1000,...
                    z_1.*1000,'*');
                global cmd_pressure feedback_pressure
                cmd_pressure=DAQ_cmd_pressure;
                feedback_pressure=DAQ_pressure;
               
                
            end
            disp("output available")
        end

        % Value changed function: COM
        function COMValueChanged(app, event)
            global aurora_device
            value = app.COM.Value;
            
            if value=="COM3"
                aurora_device=AuroraDriver('COM3');
            elseif value=="COM1"
                aurora_device=AuroraDriver('COM1');
            elseif value=="COM2"
                aurora_device=AuroraDriver('COM2');
                
            elseif value=="COM4"
                aurora_device=AuroraDriver('COM4');
            elseif value=="COM5"
                aurora_device=AuroraDriver('COM5');
            elseif value=="COM6"
                aurora_device=AuroraDriver('COM6');
            elseif value=="COM7"
                aurora_device=AuroraDriver('COM7');
            elseif value=="COM8"
                aurora_device=AuroraDriver('COM8');
            elseif value=="COM12"
                aurora_device=AuroraDriver('COM12');
                elseif value=="COM13"
                aurora_device=AuroraDriver('COM13');
            end
        end

        % Value changed function: baudrate
        function baudrateValueChanged(app, event)
            global baudrate
            baudrate=app.baudrate.Value;
        end

        % Button pushed function: Setting2Button
        function Setting2ButtonPushed(app, event)
          
            %Disable plot option while input1 is open
            app.Setting2Button.Enable='off';
            
            %Open the input1 and pass inputs
            app.DialogApp_2=input2(app,app.AmplitudeValue_2,app.FrequencyValue_2,app.DisplacementValue_2,app.StartTimeValue_2);
        end

        % Button pushed function: Setting3Button
        function Setting3ButtonPushed(app, event)
            
            %Disable plot option while input1 is open
            app.Setting3Button.Enable='off';
            
            %Open the input1 and pass inputs
            app.DialogApp_3=input3(app,app.AmplitudeValue_3,app.FrequencyValue_3,app.DisplacementValue_3,app.StartTimeValue_3);
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            global y1 n1 y2 n2 y3 n3
            clc
            %     if app.userdefineButton.Value==1
            %
            %       plot(app.UIAxesinput,n1,y1,...
            %          n2,y2,...
            %         n3,y3)
            %    legend(app.UIAxesinput,"pressure 1","pressure 2","pressure 3")
            %   app.userdefineButton.Value=0;
            %else
            a_1=app.AmplitudeValue_1;
            f_1=app.FrequencyValue_1;
            d_1=app.DisplacementValue_1;
            st_1=app.StartTimeValue_1;
            updateplot1(app,a_1,f_1,d_1,st_1)
            
            a_2=app.AmplitudeValue_2;
            f_2=app.FrequencyValue_2;
            d_2=app.DisplacementValue_2;
            st_2=app.StartTimeValue_2;
            updateplot2(app,a_2,f_2,d_2,st_2)
            
            
            a_3=app.AmplitudeValue_3;
            f_3=app.FrequencyValue_3;
            d_3=app.DisplacementValue_3;
            st_3=app.StartTimeValue_3;
            updateplot3(app,a_3,f_3,d_3,st_3)
            
            plot(app.UIAxesinput,n1,y1,...
                n2,y2,...
                n3,y3)
            legend(app.UIAxesinput,"pressure 1","pressure 2","pressure 3")
            %end
            
            
        end

        % Button pushed function: SaveDataButton
        function SaveDataButtonPushed(app, event)
            global DAQ_pressure DAQ_cmd_pressure Aurora_position Aurora_quat Aurora_theta force torque
            %filename = ('output_data') ;
            %save(filename,'DAQ_pressure','DAQ_cmd_pressure','Aurora_position','Aurora_quat','Aurora_theta','-mat')
            if app.ModeDropDown.Value=="position tracking"
                uisave({'DAQ_pressure','DAQ_cmd_pressure','Aurora_position','Aurora_quat','Aurora_theta','force','torque'})
            elseif app.ModeDropDown.Value=="chamber actuation"
                uisave({'DAQ_pressure','DAQ_cmd_pressure'})
            elseif app.ModeDropDown.Value=="force"
                app.SaveDataButton.Enable='off';
            elseif app.ModeDropDown.Value=="stiffness identification"
                app.SaveDataButton.Enable='off';
            end
            
        end

        % Value changed function: time
        function timeValueChanged(app, event)
            value = app.time.Value;
            global time
            time=value;
        end

        % Value changed function: ControlPeriod
        function ControlPeriodValueChanged(app, event)
            value = app.ControlPeriod.Value;
            global ControlPeriod
            ControlPeriod=value;
        end

        % Value changed function: ModeDropDown
        function ModeDropDownValueChanged(app, event)
            value = app.ModeDropDown.Value;
            if value=="chamber actuation"
                app.InitializationButton.Enable='off';
                app.SaveDataButton_2.Enable='off';
                app.SaveDataButton.Enable='on';
                app.SaveDataButton_3.Enable='off';
                disp("set the input pressure")
             
            elseif value=="position tracking"
                app.InitializationButton.Enable='on';
                app.SaveDataButton_2.Enable='off';
                app.SaveDataButton.Enable='on';
                app.SaveDataButton_3.Enable='off';
                disp("initialize the Aurora Tracking System")
            elseif value=="force identification"
                app.InitializationButton.Enable='on';
                app.SaveDataButton.Enable='off';
                app.SaveDataButton_2.Enable='on';
                app.SaveDataButton_3.Enable='off';
                disp("initialize the force sensor");
            elseif value=="stiffness identification"
                app.OKButton_2.Enable='on';
                app.SaveDataButton.Enable='off';
                app.SaveDataButton_2.Enable='off';
                app.SaveDataButton_3.Enable='on';
                disp("initialize the Aurora Tracking System")
                app.pressure1ListBox.Value="constant";
                app.pressure2ListBox.Value="constant";
                app.pressure3ListBox.Value="constant";
                app.pressure1ListBox.Enable='off';
                app.pressure2ListBox.Enable='off';
                app.pressure3ListBox.Enable='off';
                
            end
        end

        % Value changed function: userdefineButton_2
        function userdefineButton_2ValueChanged(app, event)
            value = app.userdefineButton_2.Value;
            
            global y1 y2 y3 time ControlPeriod n1 n2 n3 t
            
            %select a file
            filename=uigetfile({'*.mat'});
            if filename==0
                app.userdefineButton_2.Value=0;
            else
                %load the file
            load(filename)
            %the variable have to be named as "pressure"
            y1=pressure(:,1)';
            y2=pressure(:,2)';
            y3=pressure(:,3)';
            a=size(y1);
            ControlPeriod=time/a(2);
            app.ControlPeriod.Value=ControlPeriod;
            t=0:ControlPeriod:time-ControlPeriod;
            n1=t;
            n2=t;
            n3=t;
            disp('pressure input loaded')
            end


        end

        % Button pushed function: ResetButton_2
        function ResetButton_2Pushed(app, event)
            %global DAQ_pressure DAQ_cmd_pressure Aurora_position Aurora_quat Aurora_theta
            app.SendButton.Enable='on';
            clc
            cla(app.UIAxesinput)
            cla(app.UIAxesP1)
            cla(app.UIAxesP2)
            cla(app.UIAxesP3)
            cla(app.UIAxesposition)
            cla(app.UIAxesbending)
            cla(app.UIAxes)
            cla(app.UIAxestheta)
            cla(app.UIAxesFP1)
            cla(app.UIAxesFP2)
            cla(app.UIAxesFP3)
            cla(app.UIAxesTP1)
            cla(app.UIAxesTP2)
            cla(app.UIAxesTP3)
            cla(app.UIAxesS1)
            cla(app.UIAxesS2)
            cla(app.UIAxesS3)
            cla(app.UIAxesS4)
            app.xgEditField.Value=0;
            app.ygEditField.Value=0;
            app.zgEditField.Value=0;
            app.xNmmEditField.Value=0;
            app.yNmmEditField.Value=0;
            app.zNmmEditField.Value=0;
            clear global DAQ_pressure DAQ_cmd_pressure Aurora_position Aurora_quat Aurora_theta y1 y2 y3 force force_raw torque torque_raw
            if app.ModeDropDown.Value=="position tracking"
                disp("initialize the Aurora Tracking System")
            elseif app.ModeDropDown.Value=="force identification"
                disp("initialize the force sensor")
            elseif app.ModeDropDown.Value=="chamber actuation"
                disp("set the input pressure")
            elseif app.ModeDropDown.Value=="stiffness identification"
                disp("initialize the Aurora Tracking System")
            end
        end

        % Button pushed function: SaveDataButton_2
        function SaveDataButton_2Pushed(app, event)
            global DAQ_pressure DAQ_cmd_pressure force torque 
           
            %filename = ('output_data') ;
            %save(filename,'DAQ_pressure','DAQ_cmd_pressure','Aurora_position','Aurora_quat','Aurora_theta','-mat')
            if app.ModeDropDown.Value=="force identification"
                app.SaveDataButton.Enable='on';
                uisave({'DAQ_pressure','DAQ_cmd_pressure','force','torque'})
            %elseif app.ModeDropDown.Value=="chamber actuation"
             %   app.SaveDataButton.Enable='off';
            %elseif app.ModeDropDown.Value=="position tracking"
             %   app.SaveDataButton.Enable='off';
            %elseif app.ModeDropDown.Value=="stiffness identification"
             %   app.SaveDataButton.Enable='off';
                
            end
        end

        % Value changed function: Robotlength
        function RobotlengthValueChanged(app, event)
            value = app.Robotlength.Value;
            global l Aurora_position
            l=value./1000;
            %diagram for the bending of the manipulator
            x = Aurora_position.Var1(:,1) - Aurora_position.Var1(1,1);
            y = Aurora_position.Var1(:,2) - Aurora_position.Var1(1,2);
            %0.0324 should be changed into the distance from aurora to
            %robot tip
            
            z =-0.0324-Aurora_position.Var1(:,3)+l;
            plot3(app.UIAxesbending,x.*1000,y.*1000,z.*1000,'*');
            
        end

        % Button pushed function: OKButton_2
        function OKButton_2Pushed(app, event)
        clear global DAQ_pressure DAQ_cmd_pressure Aurora_position Aurora_quat Aurora_theta force force_raw torque torque_raw
        global y1 y2 y3 pwmtemp1 pwmtemp2 pwmtemp3
            pwmtemp1=max(y1)./3;
            pwmtemp2=max(y2)./3;
            pwmtemp3=max(y3)./3;
        global t_1 x_1 y_1 z_1 delta_x delta_y delta_z Aurora_position time ControlPeriod force_applied_x force_applied_y force_applied_z stiffness_x stiffness_y stiffness_z
        if app.ModeDropDown.Value=="stiffness identification"
                send_input_stiffness2;
                t_2=Aurora_position.Time(1+end-time/ControlPeriod:end);
                x_2=Aurora_position.Var1(1+end-time/ControlPeriod:end,1);
                y_2=Aurora_position.Var1(1+end-time/ControlPeriod:end,2);
                z_2=Aurora_position.Var1(1+end-time/ControlPeriod:end,3);
                delta_x=x_2-x_1;
                delta_y=y_2-y_1;
                delta_z=z_2-z_1;
                plot(app.UIAxesS3,t_2,delta_x.*1000,...
                    t_2,delta_y.*1000,...
                    t_2,delta_z.*1000);
                legend(app.UIAxesS3,"x","y","z")
                plot3(app.UIAxesS4,x_2.*1000,y_2.*1000,z_2.*1000,'*');
                global deflection_x deflection_y deflection_z
                deflection_x=mean(delta_x(end-time/(2*ControlPeriod):end)).*1000;
                deflection_y=mean(delta_y(end-time/(2*ControlPeriod):end)).*1000;
                deflection_z=mean(delta_z(end-time/(2*ControlPeriod):end)).*1000;
                if force_applied_x==0
                    stiffness_x=0;
                else
                    stiffness_x=force_applied_x/deflection_x;
                    
                end
                if force_applied_y==0
                    stiffness_y=0;
                else
                    stiffness_y=force_applied_y/deflection_y;
                end
                if force_applied_z==0
                    stiffness_z=0;
                else
                    stiffness_z=force_applied_z/deflection_z;
                end
                app.delta_xmmEditField.Value=deflection_x;
                app.delta_ymmEditField.Value=deflection_y;
                app.delta_zmmEditField.Value=deflection_z;
                app.xNmmEditField.Value=stiffness_x;
                              
                app.yNmmEditField.Value=stiffness_y;
                
                app.zNmmEditField.Value=stiffness_z;
            end
        end

        % Button pushed function: SaveDataButton_3
        function SaveDataButton_3Pushed(app, event)
            global DAQ_pressure DAQ_cmd_pressure x_1 y_1 z_1 delta_x delta_y delta_z force_applied_x force_applied_y force_applied_z stiffness_x stiffness_y stiffness_z
            
            global cmd_pressure feedback_pressure
            DAQ_cmd_pressure=cmd_pressure;
            DAQ_pressure=feedback_pressure;
            Aurora_position=[x_1,y_1,z_1].*1000;
            position_deflection=[delta_x,delta_y,delta_z].*1000;
            force_applied=[force_applied_x,force_applied_y,force_applied_z];
            stiffness=[stiffness_x,stiffness_y,stiffness_z];
            %filename = ('output_data') ;
            %save(filename,'DAQ_pressure','DAQ_cmd_pressure','Aurora_position','Aurora_quat','Aurora_theta','-mat')
            if app.ModeDropDown.Value=="force identification"
                app.SaveDataButton.Enable='off';
            elseif app.ModeDropDown.Value=="chamber actuation"
                app.SaveDataButton.Enable='off';
            elseif app.ModeDropDown.Value=="position tracking"
                app.SaveDataButton.Enable='off';
            elseif app.ModeDropDown.Value=="stiffness identification"
                uisave({'DAQ_pressure','DAQ_cmd_pressure','Aurora_position','position_deflection','force_applied','stiffness'})
                
            end
        end

        % Value changed function: xgEditField
        function xgEditFieldValueChanged(app, event)
            value = app.xgEditField.Value;
            global force_applied_x
            force_applied_x=value./1000*9.81;
            
        end

        % Value changed function: ygEditField
        function ygEditFieldValueChanged(app, event)
            value = app.ygEditField.Value;
            global force_applied_y;
            force_applied_y=value./1000*9.81;
        end

        % Value changed function: zgEditField
        function zgEditFieldValueChanged(app, event)
            value = app.zgEditField.Value;
            global force_applied_z;
            force_applied_z=value./1000*9.81;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SettingTab = uitab(app.TabGroup);
            app.SettingTab.Title = 'Setting';
            app.UIAxesinput = uiaxes(app.SettingTab);
            title(app.UIAxesinput, 'Pressure Input')
            xlabel(app.UIAxesinput, 'time [s]')
            ylabel(app.UIAxesinput, 'pressure [bar]')
            zlabel(app.UIAxesinput, 'Z')
            app.UIAxesinput.YLim = [0 3];
            app.SendButton = uibutton(app.SettingTab, 'push');
            app.SendButton.ButtonPushedFcn = createCallbackFcn(app, @SendButtonPushed, true);
            app.SendButton.Text = 'Send';
            app.OKButton = uibutton(app.SettingTab, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.Image = uiimage(app.SettingTab);
            app.Image.ImageSource = 'logo.png';
            app.TextArea = uitextarea(app.SettingTab);
            app.ResetButton_2 = uibutton(app.SettingTab, 'push');
            app.ResetButton_2.ButtonPushedFcn = createCallbackFcn(app, @ResetButton_2Pushed, true);
            app.ResetButton_2.Text = 'Reset';
            app.Panel = uipanel(app.SettingTab);
            app.Setting1Button = uibutton(app.Panel, 'push');
            app.Setting1Button.ButtonPushedFcn = createCallbackFcn(app, @Setting1ButtonPushed, true);
            app.Setting1Button.Text = 'Setting 1';
            app.Setting2Button = uibutton(app.Panel, 'push');
            app.Setting2Button.ButtonPushedFcn = createCallbackFcn(app, @Setting2ButtonPushed, true);
            app.Setting2Button.Text = 'Setting 2';
            app.Setting3Button = uibutton(app.Panel, 'push');
            app.Setting3Button.ButtonPushedFcn = createCallbackFcn(app, @Setting3ButtonPushed, true);
            app.Setting3Button.Text = 'Setting 3';
            app.userdefineButton_2 = uibutton(app.Panel, 'state');
            app.userdefineButton_2.ValueChangedFcn = createCallbackFcn(app, @userdefineButton_2ValueChanged, true);
            app.userdefineButton_2.Text = 'user define';
            app.pressure1ListBoxLabel = uilabel(app.Panel);
            app.pressure1ListBoxLabel.Text = 'pressure1';
            app.pressure1ListBox = uilistbox(app.Panel);
            app.pressure1ListBox.Items = {'constant', 'sine', 'triangle', 'step', 'user define'};
            app.pressure1ListBox.Value = 'constant';
            app.pressure2ListBoxLabel = uilabel(app.Panel);
            app.pressure2ListBoxLabel.Text = 'pressure 2';
            app.pressure2ListBox = uilistbox(app.Panel);
            app.pressure2ListBox.Items = {'constant', 'sine', 'triangle', 'step', 'user define'};
            app.pressure2ListBox.Value = 'constant';
            app.pressure3ListBoxLabel = uilabel(app.Panel);
            app.pressure3ListBoxLabel.Text = 'pressure 3';
            app.pressure3ListBox = uilistbox(app.Panel);
            app.pressure3ListBox.Items = {'constant', 'sine', 'triangle', 'step', 'user define'};
            app.pressure3ListBox.Value = 'constant';
            app.Panel_2 = uipanel(app.SettingTab);
            app.AuroraTrackingSystemLabel_2 = uilabel(app.Panel_2);
            app.AuroraTrackingSystemLabel_2.Text = 'Aurora Tracking System:';
            app.InitializationButton = uibutton(app.Panel_2, 'push');
            app.InitializationButton.ButtonPushedFcn = createCallbackFcn(app, @InitializationButtonPushed, true);
            app.InitializationButton.Text = 'Initialization';
            app.ModeDropDownLabel = uilabel(app.Panel_2);
            app.ModeDropDownLabel.Text = 'Mode';
            app.ModeDropDown = uidropdown(app.Panel_2);
            app.ModeDropDown.Items = {'position tracking', 'chamber actuation', 'force identification', 'stiffness identification'};
            app.ModeDropDown.ValueChangedFcn = createCallbackFcn(app, @ModeDropDownValueChanged, true);
            app.ModeDropDown.Value = 'position tracking';
            app.PortLabel = uilabel(app.Panel_2);
            app.PortLabel.Text = 'Port';
            app.COM = uidropdown(app.Panel_2);
            app.COM.Items = {'COM1', 'COM2', 'COM3', 'COM4', 'COM5', 'COM6', 'COM7', 'COM8', 'COM12', 'COM13'};
            app.COM.ValueChangedFcn = createCallbackFcn(app, @COMValueChanged, true);
            app.COM.Value = 'COM4';
            app.BaudRateLabel = uilabel(app.Panel_2);
            app.BaudRateLabel.Text = 'Baud Rate';
            app.baudrate = uieditfield(app.Panel_2, 'numeric');
            app.baudrate.ValueChangedFcn = createCallbackFcn(app, @baudrateValueChanged, true);
            app.TimesLabel = uilabel(app.Panel_2);
            app.TimesLabel.Text = 'Time (s)';
            app.time = uieditfield(app.Panel_2, 'numeric');
            app.time.ValueChangedFcn = createCallbackFcn(app, @timeValueChanged, true);
            app.ControlPeriodsLabel = uilabel(app.Panel_2);
            app.ControlPeriodsLabel.Text = 'Control Period (s)';
            app.ControlPeriod = uieditfield(app.Panel_2, 'numeric');
            app.ControlPeriod.ValueChangedFcn = createCallbackFcn(app, @ControlPeriodValueChanged, true);
            app.PositionPressureTab = uitab(app.TabGroup);
            app.PositionPressureTab.Title = 'Position&Pressure';
            app.SaveDataButton = uibutton(app.PositionPressureTab, 'push');
            app.SaveDataButton.ButtonPushedFcn = createCallbackFcn(app, @SaveDataButtonPushed, true);
            app.SaveDataButton.Text = 'Save Data';
            app.Image_2 = uiimage(app.PositionPressureTab);
            app.Image_2.ImageSource = 'logo.png';
            app.PressurePanel = uipanel(app.PositionPressureTab);
            app.PressurePanel.Title = 'Pressure';
            app.UIAxesP1 = uiaxes(app.PressurePanel);
            title(app.UIAxesP1, 'Pressure 1')
            xlabel(app.UIAxesP1, 'time [s]')
            ylabel(app.UIAxesP1, 'pressure [bar]')
            zlabel(app.UIAxesP1, 'Z')
            app.UIAxesP1.YLim = [0 3];
            app.UIAxesP2 = uiaxes(app.PressurePanel);
            title(app.UIAxesP2, 'Pressure 2')
            xlabel(app.UIAxesP2, 'time [s]')
            ylabel(app.UIAxesP2, 'pressure [bar]')
            app.UIAxesP2.YLim = [0 3];
            app.UIAxesP3 = uiaxes(app.PressurePanel);
            title(app.UIAxesP3, 'Pressure 3')
            xlabel(app.UIAxesP3, 'time [s]')
            ylabel(app.UIAxesP3, 'pressure [bar]')
            app.UIAxesP3.YLim = [0 3];
            app.PositionPanel = uipanel(app.PositionPressureTab);
            app.PositionPanel.Title = 'Position';
            app.UIAxes = uiaxes(app.PositionPanel);
            title(app.UIAxes, 'Position w.r.t Aurora')
            xlabel(app.UIAxes, 'X [mm]')
            ylabel(app.UIAxes, 'Y [mm]')
            app.UIAxes.XLim = [-0.1 0.1];
            app.UIAxes.YLim = [-0.1 0.1];
            app.UIAxes.ZLim = [-0.5 -0.4];
            app.UIAxesposition = uiaxes(app.PositionPanel);
            title(app.UIAxesposition, 'Postion')
            xlabel(app.UIAxesposition, 'time [s]')
            ylabel(app.UIAxesposition, 'position [mm]')
            zlabel(app.UIAxesposition, 'Z')
            app.UIAxestheta = uiaxes(app.PositionPanel);
            title(app.UIAxestheta, 'Bending Angle')
            xlabel(app.UIAxestheta, 'time [s]')
            ylabel(app.UIAxestheta, 'angle [deg]')
            app.UIAxesbending = uiaxes(app.PositionPanel);
            title(app.UIAxesbending, 'Position w.r.t Robot')
            xlabel(app.UIAxesbending, 'X [mm]')
            ylabel(app.UIAxesbending, 'Y [mm]')
            app.UIAxesbending.XLim = [-0.1 0.1];
            app.UIAxesbending.YLim = [-0.1 0.1];
            app.UIAxesbending.ZLim = [0 0.1];
            app.RobotlengthmmLabel = uilabel(app.PositionPanel);
            app.RobotlengthmmLabel.Text = 'Robot length [mm]';
            app.Robotlength = uieditfield(app.PositionPanel, 'numeric');
            app.Robotlength.ValueChangedFcn = createCallbackFcn(app, @RobotlengthValueChanged, true);
            app.ForceTab = uitab(app.TabGroup);
            app.ForceTab.Title = 'Force';
            app.SaveDataButton_2 = uibutton(app.ForceTab, 'push');
            app.SaveDataButton_2.ButtonPushedFcn = createCallbackFcn(app, @SaveDataButton_2Pushed, true);
            app.SaveDataButton_2.Text = 'Save Data';
            app.Image2 = uiimage(app.ForceTab);
            app.Image2.ImageSource = 'logo.png';
            app.ForcePanel = uipanel(app.ForceTab);
            app.ForcePanel.Title = 'Force';
            app.UIAxesFP1 = uiaxes(app.ForcePanel);
            title(app.UIAxesFP1, 'Force vs Pressure1')
            xlabel(app.UIAxesFP1, 'Pressure 1 [bar]')
            ylabel(app.UIAxesFP1, 'Force [N]')
            app.UIAxesFP2 = uiaxes(app.ForcePanel);
            title(app.UIAxesFP2, 'Froce vs Pressure 2')
            xlabel(app.UIAxesFP2, 'Pressure 2 [bar]')
            ylabel(app.UIAxesFP2, 'Force [N]')
            app.UIAxesFP3 = uiaxes(app.ForcePanel);
            title(app.UIAxesFP3, 'Force vs Pressure 3')
            xlabel(app.UIAxesFP3, 'Pressure 3 [bar]')
            ylabel(app.UIAxesFP3, 'Froce [N]')
            app.Panel_4 = uipanel(app.ForcePanel);
            app.UIAxesFT = uiaxes(app.Panel_4);
            title(app.UIAxesFT, 'Force vs Time')
            xlabel(app.UIAxesFT, 'Time [s]')
            ylabel(app.UIAxesFT, 'Force [N]')
            app.TorquePanel = uipanel(app.ForceTab);
            app.TorquePanel.Title = 'Torque';
            app.UIAxesTP1 = uiaxes(app.TorquePanel);
            title(app.UIAxesTP1, 'Torque vs Pressure 1')
            xlabel(app.UIAxesTP1, 'Pressure 1 [bar]')
            ylabel(app.UIAxesTP1, 'Torque [Nm]')
            app.UIAxesTP2 = uiaxes(app.TorquePanel);
            title(app.UIAxesTP2, 'Torque vs Pressure 2')
            xlabel(app.UIAxesTP2, 'Pressure 2 [bar]')
            ylabel(app.UIAxesTP2, 'Torque [Nm]')
            app.UIAxesTP3 = uiaxes(app.TorquePanel);
            title(app.UIAxesTP3, 'Torque vs Pressure 3')
            xlabel(app.UIAxesTP3, 'Pressure 3 [bar]')
            ylabel(app.UIAxesTP3, 'Torque [Nm]')
            app.Panel_5 = uipanel(app.TorquePanel);
            app.UIAxesTT = uiaxes(app.Panel_5);
            title(app.UIAxesTT, 'Torque vs Time')
            xlabel(app.UIAxesTT, 'Time [s]')
            ylabel(app.UIAxesTT, 'Torque [Nm]')
            app.StiffnessTab = uitab(app.TabGroup);
            app.StiffnessTab.Title = 'Stiffness';
            app.SaveDataButton_3 = uibutton(app.StiffnessTab, 'push');
            app.SaveDataButton_3.ButtonPushedFcn = createCallbackFcn(app, @SaveDataButton_3Pushed, true);
            app.SaveDataButton_3.Text = 'Save Data';
            app.PositionPanel_2 = uipanel(app.StiffnessTab);
            app.PositionPanel_2.Title = 'Position';
            app.UIAxesS1 = uiaxes(app.PositionPanel_2);
            title(app.UIAxesS1, 'Position 1')
            xlabel(app.UIAxesS1, 'time [s]')
            ylabel(app.UIAxesS1, 'position [mm]')
            app.UIAxesS2 = uiaxes(app.PositionPanel_2);
            title(app.UIAxesS2, 'Position w.r.t Aurora')
            xlabel(app.UIAxesS2, 'X [mm]')
            ylabel(app.UIAxesS2, 'Y [mm]')
            app.StiffnessPanel = uipanel(app.StiffnessTab);
            app.StiffnessPanel.Title = 'Stiffness';
            app.UIAxesS3 = uiaxes(app.StiffnessPanel);
            title(app.UIAxesS3, 'Deflection from Position 1')
            xlabel(app.UIAxesS3, 'time [s]')
            ylabel(app.UIAxesS3, 'displacement [mm]')
            app.UIAxesS4 = uiaxes(app.StiffnessPanel);
            title(app.UIAxesS4, 'Position w.r.t Aurora')
            xlabel(app.UIAxesS4, 'X [mm]')
            ylabel(app.UIAxesS4, 'Y [mm]')
            app.StiffnessPanel_2 = uipanel(app.StiffnessPanel);
            app.StiffnessPanel_2.Title = 'Stiffness';
            app.xNmmEditFieldLabel = uilabel(app.StiffnessPanel_2);
            app.xNmmEditFieldLabel.Text = 'x [N/mm]';
            app.xNmmEditField = uieditfield(app.StiffnessPanel_2, 'numeric');
            app.yNmmEditFieldLabel = uilabel(app.StiffnessPanel_2);
            app.yNmmEditFieldLabel.Text = 'y [N/mm]';
            app.yNmmEditField = uieditfield(app.StiffnessPanel_2, 'numeric');
            app.zNmmEditFieldLabel = uilabel(app.StiffnessPanel_2);
            app.zNmmEditFieldLabel.Text = 'z [N/mm]';
            app.zNmmEditField = uieditfield(app.StiffnessPanel_2, 'numeric');
            app.Panel_3 = uipanel(app.StiffnessPanel);
            app.xgEditFieldLabel = uilabel(app.Panel_3);
            app.xgEditFieldLabel.Text = 'x [g]';
            app.xgEditField = uieditfield(app.Panel_3, 'numeric');
            app.xgEditField.ValueChangedFcn = createCallbackFcn(app, @xgEditFieldValueChanged, true);
            app.ygEditFieldLabel = uilabel(app.Panel_3);
            app.ygEditFieldLabel.Text = 'y [g]';
            app.ygEditField = uieditfield(app.Panel_3, 'numeric');
            app.ygEditField.ValueChangedFcn = createCallbackFcn(app, @ygEditFieldValueChanged, true);
            app.zgEditFieldLabel = uilabel(app.Panel_3);
            app.zgEditFieldLabel.Text = 'z [g]';
            app.zgEditField = uieditfield(app.Panel_3, 'numeric');
            app.zgEditField.ValueChangedFcn = createCallbackFcn(app, @zgEditFieldValueChanged, true);
            app.OKButton_2 = uibutton(app.Panel_3, 'push');
            app.OKButton_2.ButtonPushedFcn = createCallbackFcn(app, @OKButton_2Pushed, true);
            app.OKButton_2.Text = 'OK';
            app.LoadLabel = uilabel(app.Panel_3);
            app.LoadLabel.Text = 'Load:';
            app.DefelctionPanel = uipanel(app.StiffnessPanel);
            app.DefelctionPanel.Title = 'Defelction';
            app.delta_xmmEditFieldLabel = uilabel(app.DefelctionPanel);
            app.delta_xmmEditFieldLabel.Text = 'delta_x [mm]';
            app.delta_xmmEditField = uieditfield(app.DefelctionPanel, 'numeric');
            app.delta_ymmEditFieldLabel = uilabel(app.DefelctionPanel);
            app.delta_ymmEditFieldLabel.Text = 'delta_y [mm]';
            app.delta_ymmEditField = uieditfield(app.DefelctionPanel, 'numeric');
            app.delta_zmmEditFieldLabel = uilabel(app.DefelctionPanel);
            app.delta_zmmEditFieldLabel.Text = 'delta_z [mm]';
            app.delta_zmmEditField = uieditfield(app.DefelctionPanel, 'numeric');
            app.Image3 = uiimage(app.StiffnessTab);
            app.Image3.ImageSource = 'logo.png';
            app.InstructionTab = uitab(app.TabGroup);
            app.InstructionTab.Title = 'Instruction';
            app.TextArea_2 = uitextarea(app.InstructionTab);
            app.TextArea_2.Value = {'Port: See NI Max- Devices and Interfaces'; 'Baud Rate:  9600; 14400; 19200; 38400; 57600; 115200; 921600; 230400'; 'Input Pressure: 0.003 bar < Pressure < 3bar'; ''; ' '; 'Steps:'; '-Select the mode. '; '-Press "Initialization" to initialize the Aurora tracking system or force sensor. For the Aurora System, users need to set the port and the baud rate before initialization could be done. '; '-Set the time, control period, and input pressure. For the input pressure, there are four default wave types: constant wave, sine wave, triangular wave, and step wave. By pressing "setting", the wave pattern could be edited. After all the pressure input settings are done, press "OK" to see the expected input. When using "user defined" pressure, the control period is generated automatically.'; '-Press "send" to send the input pressure, wait for seconds before output generated. Users could go to the output pages to check and save the data. '; '-Press "Reset" in the setting page to clear all the data before generating the next test.'; ''; 'Mode:'; 'Position tracking: to track the position of the manipulator under differnt pressure. Aurora System needs to be initialized.'; 'Chamber actuation: to actuate the soft robot and verify the pressure input by comparing to the command pressure. '; 'Force identification: measuring the force and torque of the manipulator by the force sensor. Force Sensor needs to be initialized.'; 'Stiffness identification: comparing the position of the manipulator with and without load applied. Aurora Systme needs to be initialized. In this mode, only constant pressure input is accepted.'; ''; ''};
            app.Image4 = uiimage(app.InstructionTab);
            app.Image4.ImageSource = 'logo.png';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mainapp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end